classdef PI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label                    matlab.ui.control.Label
        LengthEditFieldLabel     matlab.ui.control.Label
        LengthEditField          matlab.ui.control.NumericEditField
        WidthEditFieldLabel      matlab.ui.control.Label
        WidthEditField           matlab.ui.control.NumericEditField
        MaxVolumeEditFieldLabel  matlab.ui.control.Label
        MaxVolumeEditField       matlab.ui.control.NumericEditField
        BestxEditFieldLabel      matlab.ui.control.Label
        BestxEditField           matlab.ui.control.NumericEditField
        IncrementEditFieldLabel  matlab.ui.control.Label
        IncrementEditField       matlab.ui.control.NumericEditField
        CalculateButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            L=app.LengthEditField.Value;
            W=app.WidthEditField.Value;
            I=app.IncrementEditField.Value;
          x=[0:I:W/2];
           V=(L-2.*x).*(W-2.*x).*x;
           [largest_V,location]=max(V);
           app.MaxVolumeEditField.Value=largest_V;
           app.BestxEditField.Value=x(location);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '王宇涵2220213666';
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxVolumeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxVolumeEditFieldLabel.Text = 'Max Volume';
            app.MaxVolumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BestxEditFieldLabel = uilabel(app.UIFigure);
            app.BestxEditFieldLabel.Text = 'Best x';
            app.BestxEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncrementEditFieldLabel = uilabel(app.UIFigure);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end