classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TShirtProductionCostCalculatorLabel  matlab.ui.control.Label
        NumbersofshirtsEditFieldLabel   matlab.ui.control.Label
        NumbersofshirtsEditField        matlab.ui.control.NumericEditField
        DesignlengthEditFieldLabel      matlab.ui.control.Label
        DesignlengthEditField           matlab.ui.control.NumericEditField
        DesignWidthEditFieldLabel       matlab.ui.control.Label
        DesignWidthEditField            matlab.ui.control.NumericEditField
        NumberofcolorsEditFieldLabel    matlab.ui.control.Label
        NumberofcolorsEditField         matlab.ui.control.NumericEditField
        Machine1TotalCostEditFieldLabel  matlab.ui.control.Label
        Machine1TotalCostEditField      matlab.ui.control.NumericEditField
        Machine2TotalCostEditFieldLabel  matlab.ui.control.Label
        Machine2TotalCostEditField      matlab.ui.control.NumericEditField
        Machine3TotalCostEditFieldLabel  matlab.ui.control.Label
        Machine3TotalCostEditField      matlab.ui.control.NumericEditField
        MinimumTotalCostEditFieldLabel  matlab.ui.control.Label
        MinimumTotalCostEditField       matlab.ui.control.NumericEditField
        BestMachineChoiceEditFieldLabel  matlab.ui.control.Label
        BestMachineChoiceEditField      matlab.ui.control.NumericEditField
        MetallicInkDropDownLabel        matlab.ui.control.Label
        MetallicInkDropDown             matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Input
            N=app.NumbersofshirtsEditField.Value;
            L=app.DesignlengthEditField.Value;
            W=app.DesignWidthEditField.Value;
            C=app.NumberofcolorsEditField.Value;
            MetallicInk=app.MetallicInkDropDown.Value;
            xlim(app.UIAxes,[N/2,2*N])
            N1=linspace(1,2*N,200);
            switch MetallicInk
                case 'Yes'
                    a=10*W*C+40+0.001*L*W*C*N+2*N;
                 b=0.1*L*W*C+20+0.02*L*W*C*N+2*N;
                 c=0+15+0.12*L*W*N+2*N;
                 A=10*W*C+40+0.001*L*W*C.*N1+2.*N1;
                A1=A./N1;
               cla(app.UIAxes)
                plot(app.UIAxes,N1,A1,'r');
                hold(app.UIAxes,"on");
                 B=0.1*L*W*C+20+0.02*L*W*C.*N1+2.*N1;
                 B1=B./N1;
                 plot(app.UIAxes,N1,B1,'g--');
                 hold(app.UIAxes,"on");
                 C=0+15+0.12*L*W.*N1+2.*N1;
                 C1=C./N1;
                 plot(app.UIAxes,N1,C1,'b');
                 hold(app.UIAxes,"on");
                
                case 'No'
                    a=10*W*C+40+0.001*L*W*C*N;
                    b=0.1*L*W*C+20+0.02*L*W*C*N;
                    c=0+15+0.12*L*W*N;
                   A=10*W*C+40+0.001*L*W*C.*N1;
                    A2=A./N1;
                  cla(app.UIAxes)
                    plot(app.UIAxes,N1,A2,'r');
                    hold(app.UIAxes,"on");
                    B=0.1*L*W*C+20+0.02*L*W*C.*N1;
                    B2=B./N1;
                    plot(app.UIAxes,N1,B2,'g--');
                    hold(app.UIAxes,"on");
                    C=0+15+0.12*L*W.*N1;
                    C2=C./N1;
                    plot(app.UIAxes,N1,C2,'b');
                    hold(app.UIAxes,"on");
                    
            end
           
            % Output
            app.Machine1TotalCostEditField.Value=a;
            app.Machine2TotalCostEditField.Value=b;
            app.Machine3TotalCostEditField.Value=c;
            K=[a b c];
            [Z,J]=min(K);
            app.BestMachineChoiceEditField.Value=J;
            app.MinimumTotalCostEditField.Value=Z;
            Z1=Z/N;
             plot(app.UIAxes,N,Z1,'c*')
          legend(app.UIAxes,'Machine1','Machine2','Machine3','Best Machine Chioice')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TShirtProductionCostCalculatorLabel = uilabel(app.UIFigure);
            app.TShirtProductionCostCalculatorLabel.Text = 'T-Shirt Production Cost Calculator ';
            app.NumbersofshirtsEditFieldLabel = uilabel(app.UIFigure);
            app.NumbersofshirtsEditFieldLabel.Text = 'Numbers of shirts';
            app.NumbersofshirtsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesignlengthEditFieldLabel = uilabel(app.UIFigure);
            app.DesignlengthEditFieldLabel.Text = 'Design length';
            app.DesignlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesignWidthEditFieldLabel = uilabel(app.UIFigure);
            app.DesignWidthEditFieldLabel.Text = 'Design Width';
            app.DesignWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofcolorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofcolorsEditFieldLabel.Text = 'Number of colors';
            app.NumberofcolorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Machine1TotalCostEditFieldLabel = uilabel(app.UIFigure);
            app.Machine1TotalCostEditFieldLabel.Text = 'Machine1 Total Cost';
            app.Machine1TotalCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.Machine2TotalCostEditFieldLabel = uilabel(app.UIFigure);
            app.Machine2TotalCostEditFieldLabel.Text = 'Machine2 Total Cost';
            app.Machine2TotalCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.Machine3TotalCostEditFieldLabel = uilabel(app.UIFigure);
            app.Machine3TotalCostEditFieldLabel.Text = 'Machine3 Total Cost';
            app.Machine3TotalCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumTotalCostEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumTotalCostEditFieldLabel.Text = 'Minimum Total Cost';
            app.MinimumTotalCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.BestMachineChoiceEditFieldLabel = uilabel(app.UIFigure);
            app.BestMachineChoiceEditFieldLabel.Text = 'Best Machine Choice';
            app.BestMachineChoiceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MetallicInkDropDownLabel = uilabel(app.UIFigure);
            app.MetallicInkDropDownLabel.Text = 'Metallic Ink';
            app.MetallicInkDropDown = uidropdown(app.UIFigure);
            app.MetallicInkDropDown.Items = {'Yes', 'No', ''};
            app.MetallicInkDropDown.Value = 'Yes';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '王宇涵 2220213666';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Numbers of shirts vs Cost per shirt')
            xlabel(app.UIAxes, 'Numbers of shirts')
            ylabel(app.UIAxes, 'Cost per shirt')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end