classdef appS5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        RainbowButton              matlab.ui.control.Button
        RainbowLabel               matlab.ui.control.Label
        RainbowwithoutpurpleLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RainbowButton
        function RainbowButtonPushed(app, event)
            % Section 5
            rainbow=imread('Rainbow.jpg');
            app.Image.ImageSource=rainbow;
            pause(2);
            rrainbow=rainbow(:,:,1);
            grainbow=rainbow(:,:,2);
            brainbow=rainbow(:,:,3);
            pause(1);
            prainbow=rainbow;
            mask_purple1=rrainbow>80&grainbow>20&brainbow>99;
            mask_purple=cat(3,mask_purple1,mask_purple1,mask_purple1);
            prainbow(mask_purple)=255;
            app.Image2.ImageSource=prainbow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.RainbowButton = uibutton(app.UIFigure, 'push');
            app.RainbowButton.ButtonPushedFcn = createCallbackFcn(app, @RainbowButtonPushed, true);
            app.RainbowButton.Text = 'Rainbow';
            app.RainbowLabel = uilabel(app.UIFigure);
            app.RainbowLabel.Text = 'Rainbow';
            app.RainbowwithoutpurpleLabel = uilabel(app.UIFigure);
            app.RainbowwithoutpurpleLabel.Text = 'Rainbow without purple';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appS5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end