classdef ladder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HFEditFieldLabel          matlab.ui.control.Label
        HFEditField               matlab.ui.control.NumericEditField
        LMDEditFieldLabel         matlab.ui.control.Label
        LMDEditField              matlab.ui.control.NumericEditField
        LMAXDEditFieldLabel       matlab.ui.control.Label
        LMAXDEditField            matlab.ui.control.NumericEditField
        INCRCEditFieldLabel       matlab.ui.control.Label
        INCRCEditField            matlab.ui.control.NumericEditField
        CalcuateButton            matlab.ui.control.Button
        InputLabel                matlab.ui.control.Label
        OutputLabel               matlab.ui.control.Label
        BESTLADDEREditFieldLabel  matlab.ui.control.Label
        BESTLADDEREditField       matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcuateButton
        function CalcuateButtonPushed(app, event)
            % Input
            HF=app.HFEditField.Value;
            LDMIN=app.LMDEditField.Value;
            LDMAX=app.LMAXDEditField.Value;
            INCR=app.INCRCEditField.Value;
            % Internal Calculations
            X=[LDMIN+INCR:INCR:LDMAX];
            Y=(HF.*X)./(X-LDMIN);
            BL=sqrt(X.^2+Y.^2);
            [minBL,index]=min(BL);
             app.BESTLADDEREditField.Value=minBL;
             plot(app.UIAxes,X,BL,'g-')
             hold(app.UIAxes,"on")
            plot(app.UIAxes,X(index),BL(index),'ro')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HFEditFieldLabel = uilabel(app.UIFigure);
            app.HFEditFieldLabel.Text = 'HF';
            app.HFEditField = uieditfield(app.UIFigure, 'numeric');
            app.LMDEditFieldLabel = uilabel(app.UIFigure);
            app.LMDEditFieldLabel.Text = 'LMD';
            app.LMDEditField = uieditfield(app.UIFigure, 'numeric');
            app.LMAXDEditFieldLabel = uilabel(app.UIFigure);
            app.LMAXDEditFieldLabel.Text = 'LMAXD';
            app.LMAXDEditField = uieditfield(app.UIFigure, 'numeric');
            app.INCRCEditFieldLabel = uilabel(app.UIFigure);
            app.INCRCEditFieldLabel.Text = 'INCRC';
            app.INCRCEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcuateButton = uibutton(app.UIFigure, 'push');
            app.CalcuateButton.ButtonPushedFcn = createCallbackFcn(app, @CalcuateButtonPushed, true);
            app.CalcuateButton.Text = 'Calcuate';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.BESTLADDEREditFieldLabel = uilabel(app.UIFigure);
            app.BESTLADDEREditFieldLabel.Text = 'BESTLADDER';
            app.BESTLADDEREditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ladder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end