classdef p4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        FitDataButton           matlab.ui.control.Button
        RMSErrorEditFieldLabel  matlab.ui.control.Label
        RMSErrorEditField       matlab.ui.control.NumericEditField
        datasetEditFieldLabel   matlab.ui.control.Label
        datasetEditField        matlab.ui.control.EditField
        OPolyEditFieldLabel     matlab.ui.control.Label
        OPolyEditField          matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FitDataButton
        function FitDataButtonPushed(app, event)
            c3 = [0.74 0.97 1.1 0.86]; % data source
x = [-1:0.1:1]; % sample range for x
y = polyval(c3,x); % sample cubic at x
 
noisetxt=app.datasetEditField.Value;% load noise
 op=app.OPolyEditField.Value;
 noise=load(noisetxt);
ey = y + noise; % make noisy data noisey
fitc = polyfit(x,ey,op);% fit noisy data with cubic produce new fitted coef
fy=polyval(fitc,x); % new fitted y
 
             
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,x,y,'g');
            plot(app.UIAxes,x,ey,'r+');
            plot(app.UIAxes,x,fy,'bd');
            op=app.OPolyEditField.Value;


%Calculate RMS

  AD=y;
  FD=fy;
  rms1=(FD-AD).^2;
  rms2=sum(rms1);
  rms3=rms2./length(AD);
  rms4=sqrt(rms3);
  rmsfinal=rms4
  app.RMSErrorEditField.Value=rmsfinal;
        end

        % Callback function
        function ResetButtonPushed(app, event)
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fitting data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FitDataButton = uibutton(app.UIFigure, 'push');
            app.FitDataButton.ButtonPushedFcn = createCallbackFcn(app, @FitDataButtonPushed, true);
            app.FitDataButton.Text = 'Fit Data';
            app.RMSErrorEditFieldLabel = uilabel(app.UIFigure);
            app.RMSErrorEditFieldLabel.Text = 'RMS Error';
            app.RMSErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMSErrorEditField.ValueDisplayFormat = '%.4f';
            app.datasetEditFieldLabel = uilabel(app.UIFigure);
            app.datasetEditFieldLabel.Text = 'dataset';
            app.datasetEditField = uieditfield(app.UIFigure, 'text');
            app.OPolyEditFieldLabel = uilabel(app.UIFigure);
            app.OPolyEditFieldLabel.Text = 'OPoly';
            app.OPolyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end